classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Button_2                       matlab.ui.control.Button
        TotalaveragepoweroftheprocessButton  matlab.ui.control.Button
        PSDButton                      matlab.ui.control.Button
        DACFButton                     matlab.ui.control.Button
        PleaseEnternthnumberofSamplestoPlotTimeACFEditField  matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        ACFButton                      matlab.ui.control.Button
        Enterj_thforACFEditField       matlab.ui.control.NumericEditField
        Enterj_thforACFEditFieldLabel  matlab.ui.control.Label
        Enteri_thforACFEditField       matlab.ui.control.NumericEditField
        Enteri_thforACFEditFieldLabel  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        nthTermtimemeanButton          matlab.ui.control.Button
        PleaseEnternthnumberofSamplestoCalculatetimemeanEditField  matlab.ui.control.NumericEditField
        PleaseEnternthnumberofSamplestoCalculatetimemeanEditFieldLabel  matlab.ui.control.Label
        EnsembleMeanButton             matlab.ui.control.Button
        PleaseEnterNumberofneededSamplestoplotEditField_2  matlab.ui.control.NumericEditField
        PleaseEnterNumberofneededSamplestoplotEditField_2Label  matlab.ui.control.Label
        ANALYSISOFSTOCHASTICPROCESSESLabel  matlab.ui.control.Label
        PleaseEnterSampleNametoopenEditField  matlab.ui.control.EditField
        PleaseEnterSampleNametoopenEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Get_file='';
        Get_M=0; % Description
        Get_nth=0;
        time_mean=0;
        i=0;
        j=0;
        n_ACF=0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: PleaseEnterSampleNametoopenEditField
        function PleaseEnterSampleNametoopenEditFieldValueChanging(app, event)
            %%%%%%%%%%%%%%_________________NO______________________%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Callback function
        function PleaseEnterNumberofneededSamplestoplotEditFieldValueChanging(app, event)
            %%%%%%%%%%%%%%_________________NO______________________%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Value changed function: 
        % PleaseEnterNumberofneededSamplestoplotEditField_2
        function PleaseEnterNumberofneededSamplestoplotEditField_2ValueChanged(app, event)
            app.Get_M=app.PleaseEnterNumberofneededSamplestoplotEditField_2.Value;
            Plot_M_samples(app.Get_file,app.Get_M,1,0);
        end

        % Button pushed function: EnsembleMeanButton
        function EnsembleMeanButtonPushed(app, event)
            Plot_M_samples(app.Get_file,app.Get_M,2,0);
        end

        % Value changed function: 
        % PleaseEnternthnumberofSamplestoCalculatetimemeanEditField
        function PleaseEnternthnumberofSamplestoCalculatetimemeanEditFieldValueChanged(app, event)
            app.Get_nth = app.PleaseEnternthnumberofSamplestoCalculatetimemeanEditField.Value;
            app.time_mean=Plot_M_samples(app.Get_file,app.Get_M,3,app.Get_nth);
        end

        % Button pushed function: nthTermtimemeanButton
        function nthTermtimemeanButtonPushed(app, event)
            app.Button.Text=num2str( app.time_mean);
        end

        % Value changed function: PleaseEnterSampleNametoopenEditField
        function PleaseEnterSampleNametoopenEditFieldValueChanged(app, event)
             app.Get_file = app.PleaseEnterSampleNametoopenEditField.Value;
            
        end

        % Value changed function: Enteri_thforACFEditField
        function Enteri_thforACFEditFieldValueChanged(app, event)
            app.i = app.Enteri_thforACFEditField.Value;
        end

        % Value changed function: Enterj_thforACFEditField
        function Enterj_thforACFEditFieldValueChanged(app, event)
             app.j = app.Enterj_thforACFEditField.Value;
             app.ACFButton.Text=num2str(ith_jth(app.Get_file,app.i,app.j));
        end

        % Value changed function: 
        % PleaseEnternthnumberofSamplestoPlotTimeACFEditField
        function PleaseEnternthnumberofSamplestoPlotTimeACFEditFieldValueChanged(app, event)
            app.n_ACF = app.PleaseEnternthnumberofSamplestoPlotTimeACFEditField.Value;
            Time__ACF(app.Get_file,app.n_ACF);
        end

        % Button pushed function: DACFButton
        function DACFButtonPushed(app, event)
            ACF_3D(app.Get_file);
        end

        % Button pushed function: PSDButton
        function PSDButtonPushed(app, event)
            PSD_(app.Get_file);
        end

        % Button pushed function: TotalaveragepoweroftheprocessButton
        function TotalaveragepoweroftheprocessButtonPushed(app, event)
            app.Button_2.Text=num2str(Avg_power(app.Get_file));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PleaseEnterSampleNametoopenEditFieldLabel = uilabel(app.UIFigure);
            app.PleaseEnterSampleNametoopenEditFieldLabel.Text = 'Please, Enter Sample Name to open:';
            app.PleaseEnterSampleNametoopenEditField = uieditfield(app.UIFigure, 'text');
            app.PleaseEnterSampleNametoopenEditField.ValueChangedFcn = createCallbackFcn(app, @PleaseEnterSampleNametoopenEditFieldValueChanged, true);
            app.PleaseEnterSampleNametoopenEditField.ValueChangingFcn = createCallbackFcn(app, @PleaseEnterSampleNametoopenEditFieldValueChanging, true);
            app.PleaseEnterSampleNametoopenEditField.Tag = 'Get';
            app.ANALYSISOFSTOCHASTICPROCESSESLabel = uilabel(app.UIFigure);
            app.ANALYSISOFSTOCHASTICPROCESSESLabel.Text = 'ANALYSIS OF STOCHASTIC PROCESSES';
            app.PleaseEnterNumberofneededSamplestoplotEditField_2Label = uilabel(app.UIFigure);
            app.PleaseEnterNumberofneededSamplestoplotEditField_2Label.Text = 'Please, Enter Number of needed Samples to plot:';
            app.PleaseEnterNumberofneededSamplestoplotEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PleaseEnterNumberofneededSamplestoplotEditField_2.ValueChangedFcn = createCallbackFcn(app, @PleaseEnterNumberofneededSamplestoplotEditField_2ValueChanged, true);
            app.EnsembleMeanButton = uibutton(app.UIFigure, 'push');
            app.EnsembleMeanButton.ButtonPushedFcn = createCallbackFcn(app, @EnsembleMeanButtonPushed, true);
            app.EnsembleMeanButton.Text = 'Ensemble Mean';
            app.PleaseEnternthnumberofSamplestoCalculatetimemeanEditFieldLabel = uilabel(app.UIFigure);
            app.PleaseEnternthnumberofSamplestoCalculatetimemeanEditFieldLabel.Text = 'Please, Enter nth number of Samples to Calculate time mean:';
            app.PleaseEnternthnumberofSamplestoCalculatetimemeanEditField = uieditfield(app.UIFigure, 'numeric');
            app.PleaseEnternthnumberofSamplestoCalculatetimemeanEditField.ValueChangedFcn = createCallbackFcn(app, @PleaseEnternthnumberofSamplestoCalculatetimemeanEditFieldValueChanged, true);
            app.nthTermtimemeanButton = uibutton(app.UIFigure, 'push');
            app.nthTermtimemeanButton.ButtonPushedFcn = createCallbackFcn(app, @nthTermtimemeanButtonPushed, true);
            app.nthTermtimemeanButton.Text = 'nth Term time mean';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '';
            app.Enteri_thforACFEditFieldLabel = uilabel(app.UIFigure);
            app.Enteri_thforACFEditFieldLabel.Text = 'Enter i_th for ACF';
            app.Enteri_thforACFEditField = uieditfield(app.UIFigure, 'numeric');
            app.Enteri_thforACFEditField.ValueChangedFcn = createCallbackFcn(app, @Enteri_thforACFEditFieldValueChanged, true);
            app.Enterj_thforACFEditFieldLabel = uilabel(app.UIFigure);
            app.Enterj_thforACFEditFieldLabel.Text = 'Enter j_th for ACF';
            app.Enterj_thforACFEditField = uieditfield(app.UIFigure, 'numeric');
            app.Enterj_thforACFEditField.ValueChangedFcn = createCallbackFcn(app, @Enterj_thforACFEditFieldValueChanged, true);
            app.ACFButton = uibutton(app.UIFigure, 'push');
            app.ACFButton.Text = 'ACF';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Please, Enter nth number of Samples to Plot Time ACF:';
            app.PleaseEnternthnumberofSamplestoPlotTimeACFEditField = uieditfield(app.UIFigure, 'numeric');
            app.PleaseEnternthnumberofSamplestoPlotTimeACFEditField.ValueChangedFcn = createCallbackFcn(app, @PleaseEnternthnumberofSamplestoPlotTimeACFEditFieldValueChanged, true);
            app.DACFButton = uibutton(app.UIFigure, 'push');
            app.DACFButton.ButtonPushedFcn = createCallbackFcn(app, @DACFButtonPushed, true);
            app.DACFButton.Text = '3D ACF';
            app.PSDButton = uibutton(app.UIFigure, 'push');
            app.PSDButton.ButtonPushedFcn = createCallbackFcn(app, @PSDButtonPushed, true);
            app.PSDButton.Text = 'PSD';
            app.TotalaveragepoweroftheprocessButton = uibutton(app.UIFigure, 'push');
            app.TotalaveragepoweroftheprocessButton.ButtonPushedFcn = createCallbackFcn(app, @TotalaveragepoweroftheprocessButtonPushed, true);
            app.TotalaveragepoweroftheprocessButton.Text = 'Total average power of the process';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end